classdef COVID19_Matlab_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        StatisticsTab                  matlab.ui.container.Tab
        place                          matlab.ui.container.ButtonGroup
        all                            matlab.ui.control.ToggleButton
        specific                       matlab.ui.control.ToggleButton
        TabGroup_2                     matlab.ui.container.TabGroup
        InfectionsTab                  matlab.ui.container.Tab
        ax_infections                  matlab.ui.control.UIAxes
        DeathsTab                      matlab.ui.container.Tab
        ax_deaths                      matlab.ui.control.UIAxes
        GrowthRatebyCountryTab         matlab.ui.container.Tab
        UpdateButton                   matlab.ui.control.Button
        gr_table                       matlab.ui.control.Table
        gr_hist                        matlab.ui.control.UIAxes
        PlotTypesButtonGroup           matlab.ui.container.ButtonGroup
        lin                            matlab.ui.control.ToggleButton
        log                            matlab.ui.control.ToggleButton
        add_trend_lines                matlab.ui.control.CheckBox
        daystocomputetrendLabel        matlab.ui.control.Label
        SubsetofCountriesListBoxLabel  matlab.ui.control.Label
        countries                      matlab.ui.control.ListBox
        UseLabel                       matlab.ui.control.Label
        days_for_trend                 matlab.ui.control.NumericEditField
        mostrecentdaystovalidatepredictionsLabel  matlab.ui.control.Label
        HoldoutLabel                   matlab.ui.control.Label
        days_for_pred                  matlab.ui.control.NumericEditField
        MapsTab                        matlab.ui.container.Tab
        map                            matlab.ui.control.UIAxes
        ShowMapButton                  matlab.ui.control.Button
        TimeSliderLabel                matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        TimerelativetotodaydaysLabel   matlab.ui.control.Label
        GenerateMovieButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        DATA % Description
        DATA_Deaths
        Countries
        States
        inf_vs_t
        dth_vs_t
        Lat
        Long
        dates
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../COVID-19/csse_covid_19_data/csse_covid_19_time_series/')
            
            try
                Y = importdata('time_series_19-covid-Confirmed.csv');
            catch
                [~, pathname, ~] = uigetfile('time_series_19-covid-Confirmed.csv','Navigate to find the File time_series_19-covid-Confirmed.csv');
                addpath(pathname);
                Y = importdata('time_series_19-covid-Confirmed.csv');
            end
            
            app.dates = Y.textdata(1,5:end);
            app.DATA = Y.data(:,3:end);
            app.Countries = Y.textdata(2:end,2);
            app.States = Y.textdata(2:end,1);
            app.Lat = Y.data(:,1);
            app.Long = Y.data(:,2);
            
            Y = importdata('time_series_19-covid-Deaths.csv');
            app.DATA_Deaths = Y.data(:,3:end);
            
            app.countries.Items = unique(app.Countries);
            app.countries.Value = 'US';
            
            placeSelectionChanged(app)
            
        end

        % Selection changed function: place
        function placeSelectionChanged(app, event)
            if app.specific.Value
                app.countries.Items = unique(app.Countries);
                app.countries.Value = 'US';
            end
            
            Make_Plot(app)
        end

        % Callback function: PlotTypesButtonGroup, days_for_pred, 
        % days_for_trend
        function PlotTypeButtonGroupSelectionChanged(app, event)
            Make_Plot(app)
        end

        % Value changed function: countries
        function countriesValueChanged(app, event)
            Make_Plot(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            Make_Growth_Rate_Table(app)
        end

        % Callback function: ShowMapButton, TimeSlider
        function ShowMapButtonPushed(app, event)
            Make_Map(app);
            
        end

        % Button pushed function: GenerateMovieButton
        function GenerateMovieButtonPushed(app, event)
            Make_Movie(app)           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.place = uibuttongroup(app.StatisticsTab);
            app.place.SelectionChangedFcn = createCallbackFcn(app, @placeSelectionChanged, true);
            app.place.Title = 'All Countries or Selected Subset of Countries';
            app.all = uitogglebutton(app.place);
            app.all.Text = 'All Countries';
            app.all.Value = true;
            app.specific = uitogglebutton(app.place);
            app.specific.Text = 'Specific Countires';
            app.TabGroup_2 = uitabgroup(app.StatisticsTab);
            app.InfectionsTab = uitab(app.TabGroup_2);
            app.InfectionsTab.Title = 'Infections';
            app.ax_infections = uiaxes(app.InfectionsTab);
            title(app.ax_infections, 'Infections over time')
            xlabel(app.ax_infections, 'Time (days)')
            ylabel(app.ax_infections, 'Infections')
            app.DeathsTab = uitab(app.TabGroup_2);
            app.DeathsTab.Title = 'Deaths';
            app.ax_deaths = uiaxes(app.DeathsTab);
            title(app.ax_deaths, 'Deaths over time')
            xlabel(app.ax_deaths, 'Time (days)')
            ylabel(app.ax_deaths, 'Deaths')
            app.GrowthRatebyCountryTab = uitab(app.TabGroup_2);
            app.GrowthRatebyCountryTab.Title = 'Growth Rate by Country';
            app.UpdateButton = uibutton(app.GrowthRatebyCountryTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.gr_table = uitable(app.GrowthRatebyCountryTab);
            app.gr_table.ColumnName = {'Country'; 'Doubling Time (days)'};
            app.gr_table.RowName = {};
            app.gr_hist = uiaxes(app.GrowthRatebyCountryTab);
            title(app.gr_hist, 'Histogram')
            xlabel(app.gr_hist, 'Doubling Time (days)')
            ylabel(app.gr_hist, 'Probability Density')
            app.PlotTypesButtonGroup = uibuttongroup(app.StatisticsTab);
            app.PlotTypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypesButtonGroup.Title = 'Plot Types:';
            app.lin = uitogglebutton(app.PlotTypesButtonGroup);
            app.lin.Text = 'Linear';
            app.log = uitogglebutton(app.PlotTypesButtonGroup);
            app.log.Text = 'Logarithmic';
            app.log.Value = true;
            app.add_trend_lines = uicheckbox(app.StatisticsTab);
            app.add_trend_lines.Text = 'Include Trend lines';
            app.add_trend_lines.Value = true;
            app.daystocomputetrendLabel = uilabel(app.StatisticsTab);
            app.daystocomputetrendLabel.Text = 'days to compute trend';
            app.SubsetofCountriesListBoxLabel = uilabel(app.StatisticsTab);
            app.SubsetofCountriesListBoxLabel.Text = 'Subset of Countries';
            app.countries = uilistbox(app.StatisticsTab);
            app.countries.Multiselect = 'on';
            app.countries.ValueChangedFcn = createCallbackFcn(app, @countriesValueChanged, true);
            app.countries.Value = {'Item 1'};
            app.UseLabel = uilabel(app.StatisticsTab);
            app.UseLabel.Text = 'Use';
            app.days_for_trend = uieditfield(app.StatisticsTab, 'numeric');
            app.days_for_trend.Limits = [3 20];
            app.days_for_trend.RoundFractionalValues = 'on';
            app.days_for_trend.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_trend.Value = 10;
            app.mostrecentdaystovalidatepredictionsLabel = uilabel(app.StatisticsTab);
            app.mostrecentdaystovalidatepredictionsLabel.Text = 'most recent days to validate predictions';
            app.HoldoutLabel = uilabel(app.StatisticsTab);
            app.HoldoutLabel.Text = 'Hold out';
            app.days_for_pred = uieditfield(app.StatisticsTab, 'numeric');
            app.days_for_pred.Limits = [0 10];
            app.days_for_pred.RoundFractionalValues = 'on';
            app.days_for_pred.ValueChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.days_for_pred.Value = 3;
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps';
            app.map = uiaxes(app.MapsTab);
            title(app.map, 'Map of Pandemic')
            xlabel(app.map, '')
            ylabel(app.map, '')
            app.map.XTick = [];
            app.map.YTick = [];
            app.ShowMapButton = uibutton(app.MapsTab, 'push');
            app.ShowMapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.ShowMapButton.Text = 'Show Map';
            app.TimeSliderLabel = uilabel(app.MapsTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.MapsTab);
            app.TimeSlider.Limits = [-40 0];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @ShowMapButtonPushed, true);
            app.TimerelativetotodaydaysLabel = uilabel(app.MapsTab);
            app.TimerelativetotodaydaysLabel.Text = 'Time (days) relative to today';
            app.GenerateMovieButton = uibutton(app.MapsTab, 'push');
            app.GenerateMovieButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMovieButtonPushed, true);
            app.GenerateMovieButton.Text = 'Generate Movie';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_Matlab_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end